classdef SmartPID < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        SmartPIDToolbox01UIFigure      matlab.ui.Figure
        Panel_sysident                 matlab.ui.container.Panel
        TrainingsetLabel               matlab.ui.control.Label
        EditField_trainDataName        matlab.ui.control.EditField
        ValidationsetLabel             matlab.ui.control.Label
        EditField_testDataName         matlab.ui.control.EditField
        Button_startSysIdent           matlab.ui.control.Button
        CheckBox_hyperparOpt           matlab.ui.control.CheckBox
        Panel_turningPIDcoef           matlab.ui.container.Panel
        Button_startTrainPID           matlab.ui.control.Button
        Panel_trainSmartPID            matlab.ui.container.Panel
        Button_startTrainSmartPID      matlab.ui.control.Button
        Panel_analyzeSmartPID          matlab.ui.container.Panel
        Button_startAnalyzeSmartPID    matlab.ui.control.Button
        Panel_saveSmartPIDnetwork      matlab.ui.container.Panel
        Button_saveToPath              matlab.ui.control.Button
        AutotuningNNsavepathLabel      matlab.ui.control.Label
        EditField_autotuneNetworkName  matlab.ui.control.EditField
        Panel_runAll                   matlab.ui.container.Panel
        Button_startRunAll             matlab.ui.control.Button
        StopButton                     matlab.ui.control.Button
    end


    properties (Access = public)
        system = 'smartPIDcoal2'% Description
        %%
        net % Description
        net2 % Description
        inpSz % Description
        layer1Sz % Description
        %
        IW % Description
        LW % Description
        b1 % Description
        b2 % Description
        inpR % Description
        outR % Description
        %%        
        Kp % Description
        Ki % Description
        Kd % Description
        PIDsw % Description
        %
        SEEDsw % Description
        SEED % Description
        %
        Counter % Description
        reverseStr % Description 
        %
        stop
    end

    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            open_system(strcat('models\',app.system));
        end

        % Button pushed function: Button_startSysIdent
        function Button_startSysIdentPushed(app, event)
            app.stop = false;
            identSmartPID(app.EditField_trainDataName.Value, app.EditField_testDataName.Value, app.CheckBox_hyperparOpt.Value, app);
        end

        % Button pushed function: Button_startTrainSmartPID
        function Button_startTrainSmartPIDPushed(app, event)
            app.stop = false;
            run trainSmartPID;
        end

        % Button pushed function: Button_startTrainPID
        function Button_startTrainPIDPushed(app, event)
            app.stop = false;
            run trainPID;
        end

        % Button pushed function: Button_startAnalyzeSmartPID
        function Button_startAnalyzeSmartPIDPushed(app, event)
            app.stop = false;
            run runSmartPID;
        end

        % Button pushed function: Button_saveToPath
        function Button_saveToPathPushed(app, event)
           autoTuneNNmodel = app.net;
           save(app.EditField_autotuneNetworkName.Value,'autoTuneNNmodel');
        end

        % Button pushed function: Button_startRunAll
        function Button_startRunAllPushed(app, event)
           app.stop = false;
           Button_startSysIdentPushed(app, event);           
           Button_startTrainPIDPushed(app, event);
           Button_startTrainSmartPIDPushed(app, event);
           Button_startAnalyzeSmartPIDPushed(app, event);
           Button_saveToPathPushed(app, event);
        end

        % Button pushed function: StopButton
        function StopButtonPushed(app, event)
           app.stop = true;
        end
    end

    % App initialization and construction
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.SmartPIDToolbox01UIFigure = uifigure;
            app.SmartPIDToolbox01UIFigure.Name = 'SmartPID Toolbox 0.1';
            app.Panel_sysident = uipanel(app.SmartPIDToolbox01UIFigure);
            app.Panel_sysident.Title = 'Step a: System identification of a plant model';
            app.TrainingsetLabel = uilabel(app.Panel_sysident);
            app.TrainingsetLabel.Text = 'Training set';
            app.EditField_trainDataName = uieditfield(app.Panel_sysident, 'text');
            app.EditField_trainDataName.Value = 'data\Inferno_log_2018_12_17 16-45-47.dat';
            app.ValidationsetLabel = uilabel(app.Panel_sysident);
            app.ValidationsetLabel.Text = 'Validation set';
            app.EditField_testDataName = uieditfield(app.Panel_sysident, 'text');
            app.EditField_testDataName.Value = 'data\Inferno_log_2018_12_17 16-36-05.dat';
            app.Button_startSysIdent = uibutton(app.Panel_sysident, 'push');
            app.Button_startSysIdent.ButtonPushedFcn = createCallbackFcn(app, @Button_startSysIdentPushed, true);
            app.Button_startSysIdent.Text = 'Run';
            app.CheckBox_hyperparOpt = uicheckbox(app.Panel_sysident);
            app.CheckBox_hyperparOpt.Text = 'Enable hyperparameters grid search';
            app.Panel_turningPIDcoef = uipanel(app.SmartPIDToolbox01UIFigure);
            app.Panel_turningPIDcoef.Title = 'Step b: Setting up of PID coefficients for initialization of the auto-tuning NN unit';
            app.Button_startTrainPID = uibutton(app.Panel_turningPIDcoef, 'push');
            app.Button_startTrainPID.ButtonPushedFcn = createCallbackFcn(app, @Button_startTrainPIDPushed, true);
            app.Button_startTrainPID.Text = 'Run';
            app.Panel_trainSmartPID = uipanel(app.SmartPIDToolbox01UIFigure);
            app.Panel_trainSmartPID.Title = 'Step c: Training of the PID auto-tuning NN unit';
            app.Button_startTrainSmartPID = uibutton(app.Panel_trainSmartPID, 'push');
            app.Button_startTrainSmartPID.ButtonPushedFcn = createCallbackFcn(app, @Button_startTrainSmartPIDPushed, true);
            app.Button_startTrainSmartPID.Text = 'Run';
            app.Panel_analyzeSmartPID = uipanel(app.SmartPIDToolbox01UIFigure);
            app.Panel_analyzeSmartPID.Title = 'Step d: Compare cost value for the standard and adtive PID controllers';
            app.Button_startAnalyzeSmartPID = uibutton(app.Panel_analyzeSmartPID, 'push');
            app.Button_startAnalyzeSmartPID.ButtonPushedFcn = createCallbackFcn(app, @Button_startAnalyzeSmartPIDPushed, true);
            app.Button_startAnalyzeSmartPID.Text = 'Run';
            app.Panel_saveSmartPIDnetwork = uipanel(app.SmartPIDToolbox01UIFigure);
            app.Panel_saveSmartPIDnetwork.Title = 'Step e: Saving PID auto-tuning NN unit';
            app.Button_saveToPath = uibutton(app.Panel_saveSmartPIDnetwork, 'push');
            app.Button_saveToPath.ButtonPushedFcn = createCallbackFcn(app, @Button_saveToPathPushed, true);
            app.Button_saveToPath.Text = 'Save';
            app.AutotuningNNsavepathLabel = uilabel(app.Panel_saveSmartPIDnetwork);
            app.AutotuningNNsavepathLabel.Text = 'Auto-tuning NN save path';
            app.EditField_autotuneNetworkName = uieditfield(app.Panel_saveSmartPIDnetwork, 'text');
            app.EditField_autotuneNetworkName.Value = 'models\pid_tuner_net.mat';
            app.Panel_runAll = uipanel(app.SmartPIDToolbox01UIFigure);
            app.Panel_runAll.Title = 'Sequentially run steps a-e & Stop running a-d';
            app.Button_startRunAll = uibutton(app.Panel_runAll, 'push');
            app.Button_startRunAll.ButtonPushedFcn = createCallbackFcn(app, @Button_startRunAllPushed, true);
            app.Button_startRunAll.Text = 'Run';
            app.StopButton = uibutton(app.Panel_runAll, 'push');
            app.StopButton.ButtonPushedFcn = createCallbackFcn(app, @StopButtonPushed, true);
            app.StopButton.Text = 'Stop';
        end
    end

    methods (Access = public)

        % Construct app
        function app = SmartPID

            % Create and configure components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.SmartPIDToolbox01UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.SmartPIDToolbox01UIFigure)
        end
    end
end